classdef analyze_IRcamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        FileOpenMenu          matlab.ui.container.Menu
        SelectPointEditField  matlab.ui.control.EditField
        Label_2               matlab.ui.control.Label
        SelectValueEditField  matlab.ui.control.NumericEditField
        Label                 matlab.ui.control.Label
        CaxisMinEditField     matlab.ui.control.NumericEditField
        colorbarminLabel      matlab.ui.control.Label
        CaxisMaxEditField     matlab.ui.control.NumericEditField
        colorbarmaxLabel      matlab.ui.control.Label
        MainDataDropDown      matlab.ui.control.DropDown
        MainDataLabel         matlab.ui.control.Label
        VerticalUIAxes        matlab.ui.control.UIAxes
        HorizontalUIAxes      matlab.ui.control.UIAxes
        MainImageUIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DataStruct % すべてのデータを格納する構造体
        MainData % 表示するデータ
        MainImage % MainImageUIAxesで表示している画像データ
        MainImageCrosshair % MainImageUiAxesでクリックした座標プロット
        SelectPoint % 選択している座標
        HorizontalPlot % 水平方向のプロット
        VerticalPlot % 垂直方向のプロット
    end
    
    methods (Access = private)
        
        function main_image_uiaxes_button_down_fcn(app, ~, event)
            point = event.IntersectionPoint;
            pointX = ceil(point(1))-0.5;
            pointY = ceil(point(2))-0.5;
            app.SelectPoint = point;

            % 選択している点の情報
            app.MainImageCrosshair.Position = [pointX pointY];
            app.SelectValueEditField.Value = app.MainImage.CData(pointY-0.5,pointX-0.5);
            app.SelectPointEditField.Value = mat2str(round(point));
            % 選択している点の水平垂直方向のプロット
            app.HorizontalPlot.YData = app.MainData(ceil(point(2)),:,1);
            app.VerticalPlot.XData = app.MainData(:,ceil(point(1)),1);
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FileOpenMenu
        function FileOpenMenuSelected(app, event)
            data = load_ir_data;
            if size(data,1)==0
                return
            end
            app.DataStruct = data;
            itemsData = struct2cell(app.DataStruct);
            app.MainData = itemsData{1,1};
            % 各種コンポーネントの設定
            app.MainDataDropDown.Items = fieldnames(app.DataStruct); % DropDownのラベル
            app.MainDataDropDown.ItemsData = struct2cell(app.DataStruct); % DropDownの実データ
            app.MainImage = imagesc(app.MainImageUIAxes, app.MainData(:,:,1),...
                'ButtonDownFcn', @app.main_image_uiaxes_button_down_fcn);
            hold(app.MainImageUIAxes, "on")
            app.MainImageCrosshair = drawcrosshair(...
                    'Position',[100 100],'Parent',app.MainImageUIAxes,'LineWidth',1);
            colorbar(app.MainImageUIAxes)
            caxis(app.MainImageUIAxes, ...
                [min(app.MainImage.CData,[],'all'), max(app.MainImage.CData,[],'all')])
            app.CaxisMaxEditField.Value = max(app.MainImage.CData,[],'all');
            app.CaxisMinEditField.Value = min(app.MainImage.CData,[],'all');
            % 水平垂直方向プロット
            app.HorizontalPlot = plot(app.HorizontalUIAxes, app.MainData(1,:,1));
            app.VerticalPlot = plot(app.VerticalUIAxes, app.MainData(:,1,1), 1:size(app.MainData(:,1,1),1));
            app.HorizontalUIAxes.XLim = [1, size(app.MainData(1,:,1),2)];
            app.VerticalUIAxes.YLim = [1, size(app.MainData(:,1,1),1)];
        end

        % Value changed function: CaxisMaxEditField
        function CaxisMaxEditFieldValueChanged(app, event)
            value = app.CaxisMaxEditField.Value;
            if ~(value > app.CaxisMinEditField.Value)
                msgbox("カラーバーの上限は下限より大きい値である必要があります")
                return
            end
            caxis(app.MainImageUIAxes, [app.CaxisMinEditField.Value, value])
        end

        % Value changed function: CaxisMinEditField
        function CaxisMinEditFieldValueChanged(app, event)
            value = app.CaxisMinEditField.Value;
            if ~(value < app.CaxisMaxEditField.Value)
                msgbox("カラーバーの下限は上限より小さい値である必要があります")
                return
            end
            caxis(app.MainImageUIAxes, [value, app.CaxisMaxEditField.Value])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'ファイル';
            app.FileOpenMenu = uimenu(app.FileMenu);
            app.FileOpenMenu.MenuSelectedFcn = createCallbackFcn(app, @FileOpenMenuSelected, true);
            app.FileOpenMenu.Accelerator = 'O';
            app.FileOpenMenu.Text = '開く';
            app.MainImageUIAxes = uiaxes(app.UIFigure);
            title(app.MainImageUIAxes, 'Title')
            xlabel(app.MainImageUIAxes, 'X')
            ylabel(app.MainImageUIAxes, 'Y')
            zlabel(app.MainImageUIAxes, 'Z')
            app.MainImageUIAxes.XLim = [0 640];
            app.MainImageUIAxes.YLim = [0 512];
            colormap(app.MainImageUIAxes, 'jet')
            app.HorizontalUIAxes = uiaxes(app.UIFigure);
            title(app.HorizontalUIAxes, 'horizontal')
            xlabel(app.HorizontalUIAxes, 'X')
            ylabel(app.HorizontalUIAxes, 'Y')
            zlabel(app.HorizontalUIAxes, 'Z')
            app.VerticalUIAxes = uiaxes(app.UIFigure);
            title(app.VerticalUIAxes, 'vertical')
            xlabel(app.VerticalUIAxes, 'X')
            ylabel(app.VerticalUIAxes, 'Y')
            zlabel(app.VerticalUIAxes, 'Z')
            app.MainDataLabel = uilabel(app.UIFigure);
            app.MainDataLabel.Text = '表示するデータ';
            app.MainDataDropDown = uidropdown(app.UIFigure);
            app.MainDataDropDown.Items = {};
            app.MainDataDropDown.Value = {};
            app.colorbarmaxLabel = uilabel(app.UIFigure);
            app.colorbarmaxLabel.Text = 'colorbar max';
            app.CaxisMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.CaxisMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CaxisMaxEditFieldValueChanged, true);
            app.colorbarminLabel = uilabel(app.UIFigure);
            app.colorbarminLabel.Text = 'colorbar min';
            app.CaxisMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.CaxisMinEditField.ValueChangedFcn = createCallbackFcn(app, @CaxisMinEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '選択した点の値';
            app.SelectValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectValueEditField.ValueDisplayFormat = '%.0f';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '選択した点の座標';
            app.SelectPointEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyze_IRcamera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end